classdef dynamic_mosquitoes < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    ControlstrategiesforawildmosquitoespopulationUIFigure  matlab.ui.Figure
    GridLayout                     matlab.ui.container.GridLayout
    LeftPanel                      matlab.ui.container.Panel
    LeftPanel2                     matlab.ui.container.Panel
    TemperatureEditFieldLabel_1    matlab.ui.control.Label
    TemperatureEditFieldLabel_2    matlab.ui.control.Label
    TemperatureEditField           matlab.ui.control.NumericEditField
    DateEditFieldLabel             matlab.ui.control.Label
    DateEditField                  matlab.ui.control.EditField
    ModifySITsitesButton           matlab.ui.control.Button
    ModifyreleasesperiodicityEditField  matlab.ui.control.NumericEditField
    ModifyreleasesperiodicityButton  matlab.ui.control.Button
    ModifynumberofmalesperreleaseEditField  matlab.ui.control.NumericEditField
    ModifynumberofmalesperreleaseButton  matlab.ui.control.Button
    BasicReproductionNumberEditField  matlab.ui.control.NumericEditField
    BasicReproductionNumberEditFieldLabel  matlab.ui.control.Label
    TotalSterileMalesEditField     matlab.ui.control.NumericEditField
    TotalSterileMalesEditFieldLabel  matlab.ui.control.Label
    TotalWildMalesEditField        matlab.ui.control.NumericEditField
    TotalWindMalesEditFieldLabel   matlab.ui.control.Label
    SterileMalesReleasesSwitch     matlab.ui.control.ToggleSwitch
    SterileMalesReleasesSwitchLabel  matlab.ui.control.Label
    NewsitesButton                 matlab.ui.control.Button
    DestroysitesButton             matlab.ui.control.Button
    TotalFemalesEditField          matlab.ui.control.NumericEditField
    TotalFemalesEditFieldLabel     matlab.ui.control.Label
    BreedingFemalesEditField       matlab.ui.control.NumericEditField
    BreedingFemalesEditFieldLabel  matlab.ui.control.Label
    RestingFemalesEditField        matlab.ui.control.NumericEditField
    RestingFemalesEditFieldLabel   matlab.ui.control.Label
    FeedingFemalesEditField        matlab.ui.control.NumericEditField
    FeedingFemalesEditFieldLabel   matlab.ui.control.Label
    TimeEditField                  matlab.ui.control.NumericEditField
    TimeEditFieldLabel             matlab.ui.control.Label
    CenterPanel                    matlab.ui.container.Panel
    RightPanel2                    matlab.ui.container.Panel
    UIAxes                         matlab.ui.control.UIAxes
    RightPanel                     matlab.ui.container.Panel
    UIAxes_2                       matlab.ui.control.UIAxes
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
    twoPanelWidth = 768;
  end

  properties (Access = private)
      data % Empirical data
      params % Contains all simulation parameters
      coordinates  % Contains all the coordinates
      indicator % Indicator matrixes
      plumes % Plumes's indicator matrixes
      R0 % Basic reproduction number
      sterile_males_releases_bool = false % Boolean variable for SIT activation
      sterile_males_position % Matrix with the sterile males distribution
      food_color = [1 0 0]
      egg_color = [0 0.5 1]
      smr_color = [0 0 0]
      smr_MarkerType = "h"
      smr_MarkerSize = 2
  end

  methods (Access = private)
    % ------ DATAs ------
    function make_data = make_data(app, temp_vec, values_vec, temperature)
      if length(temp_vec) == 1
        make_data_function = @(T) values_vec;
      else
        make_data_function = @(T) interp1(temp_vec, values_vec, T, "spline", "extrap");
      end
      make_data = make_data_function(temperature);
    end

    function data = init_empirical_data(app, current_temperature)
      % Biological parameters
      data.NEgg = make_data(app, [25, 26], [60, 61], current_temperature); % [female^-1 day^-1] Number of eggs laid per female
      data.K = make_data(app, 25, 1000, current_temperature); % [#] Environmental carrying capacity
      data.r = make_data(app, 25, 0.5, current_temperature); % [-] Percentage of larvae becoming female
    
      % Transition rates between compartments
      data.etaA  = max(make_data(app, [10, 15, 20, 25, 30, 35, 40], [0, 0.0236, 0.0578, 0.0671, 0.0645, 0.0515, 0], current_temperature), 0); % [?] Rate from acquatic state to adult state
      % Ho messo il minimo a etaA perché quando la temperatura supera i
      % 45°C, il suo valore va sotto lo zero
      data.betaY = make_data(app, 25, 0.085, current_temperature); % [?] Rate of emergence from young female to adult female
      data.mu_fr = make_data(app, 25, 1/2, current_temperature); % [?] Rate from blood-feeding females to resting females
      data.mu_rb = make_data(app, [20, 25, 30, 34], [0.14, 0.286, 0.4, 0.294], current_temperature); % [day^-1] Rate from resting female to ovoposition females
      data.mu_bf = make_data(app, 25, 1/3, current_temperature); % [?] Rate from ovoposition females to feeding females
    
      % Mortality
      if 10 < current_temperature && current_temperature < 40
        data.MA = min(make_data(app, [10, 15, 20, 25, 30, 35, 40], [1, 0.959, 0.4815, 0.6246, 0.6530, 0.9975, 1], current_temperature), 1); % [day^-1] Mortality rate for acquatic state
      else
        data.MA = 1;
      end
      data.MY = make_data(app, 25, 0.105, current_temperature); % [day^-1] Mortality rate for young females
      if 15 < current_temperature
        data.Mf = make_data(app, [15, 20, 25, 30, 35], [0.0259, 0.0322, 0.0322, 0.029, 0.0504], current_temperature); % [day^-1] Mortality rate for wild adult females
      else
        data.Mf = 0.0259;
      end
      if 15 <= current_temperature
        data.Mm = make_data(app, [15, 20, 25, 30, 35], [0.0319, 0.0484, 0.0512, 0.0573, 0.0671], current_temperature); % [day^-1] Mortality rate for wild adult males
      else
        data.Mm = 0.0319;
      end
      data.Mms = make_data(app, 25, 1/11.6, current_temperature); % [day^-1] Mortality for sterile adult males
    
      % Advection
      data.V = make_data(app, 25, [0; 0], current_temperature); % [m/s] Advection wind velocity
    
      % Diffusion
      data.D = 0.04;%make_data(app, 25, 0.04, current_temperature); % [m^2/s] Diffusion coefficient
    end
    
    % ------PARAMETERS ------
    function params = init_parameters(app)
      % Generic parameter
      params.toll = 1e-5; % [] Tolerance parameter
      
      % Advection
      params.Cmax = 1; % [m/s] Maximum concentrarion of attractant
      params.q = 0.1; % [] Sharpness parameter for attractant gradient
      params.NablaC = @(x, y, Ax, Ay) cat(3, ...
        params.Cmax * exp(-params.q * ((x - Ax)^2 + (y - Ay)^2)) * sign(y - Ay - params.toll), ...
        params.Cmax * exp(-params.q * ((x - Ax)^2 + (y - Ay)^2)) * sign(x - Ax - params.toll) ...
      );
      
      % Sterile Insect Technique
      params.lambda_s = 0.1; % [] Fertility level of sterile males
      params.f_s = 0.7; % [] Sexual competitivity of sterile males
      params.rho_s = 1; % [] Quality of a release
      params.Lambda_s = 2000; % [#] Numeber of sterile males released per release
      params.tau = 7; % [day] Release frequency
      params.Tstart = 0; % [day] Start time for sterile male releases
      
      % Temporal discretizzation
      params.dt = 1e-3; % [#/day] Time step
      params.T = 365; % [day] Total simulation time
      params.Nt = floor(params.T/params.dt); % [#] Number of temporal grid points
      
      % Spatial discretizzation
      params.Ly = 2; % Height of the spatial grid points along y
      params.Lx = 2; % Width of the spatial grid points along x
      params.Ny = 41; % Number of spatial grid point along y
      params.Nx = 41; % Number of spatial grid point along x
      params.dy = params.Ly / (params.Ny - 1); % Spatial discretizzation step in y-direction
      params.dx = params.Lx / (params.Nx - 1); % Spatial discretizzation step in x-direction
      params.y_vec = linspace(-params.Ly/2, params.Ly/2, params.Ny);
      params.x_vec = linspace(-params.Lx/2, params.Lx/2, params.Nx);
    end

    % ------ SITES ------
    function coordinates = get_coordinates_coordinates(app)
      coordinates.breeding  = [-0.5,-0.5; 0,-0.4; 0.5,-0.5; -0.4,0; 0.4,0; -0.5,0.5; 0,0.4; 0.5,0.5];
      coordinates.feeding = [0,-0.7; -0.7,0; 0,0; 0.7,0; 0,0.7];
      coordinates.sterile_males_releasing  = coordinates.breeding;
    end

    function indicator = build_indicator_matrix(app)
      % Indicator functions
      indicator.breeding = create_zones(app, ...
        app.params.Ly, app.params.Lx, ...
        app.params.Ny, app.params.Nx, ...
        app.params.toll, ...
        app.coordinates.breeding);
      indicator.feeding = create_zones(app, ...
        app.params.Ly, app.params.Lx, ...
        app.params.Ny, app.params.Nx, ...
        app.params.toll, ...
        app.coordinates.feeding);
      indicator.sterile_males_releasing = (app.params.Lambda_s / size(app.coordinates.sterile_males_releasing,1)) ...
        * create_zones(app, ...
            app.params.Ly, app.params.Lx, ...
            app.params.Ny, app.params.Nx, ...
            app.params.toll, ...
            app.coordinates.sterile_males_releasing);
    end

    function plumes = compute_plumes(app)
      % Plumes
      plumes.ind_pb = app.create_plumes( ...
        app.params.Ly, app.params.Lx, ...
        app.params.dy, app.params.dx, ...
        app.data.V, ...
        app.params.toll, ...
        app.coordinates.breeding);
      plumes.ind_pf = app.create_plumes( ...
        app.params.Ly, app.params.Lx, ...
        app.params.dy, app.params.dx, ...
        app.data.V, ...
        app.params.toll, ...
        app.coordinates.feeding);
    end
   
    function zone = create_zones(app, Ly, Lx, Ny, Nx, toll, coordinates)
      % Define domain
      y = linspace(-Ly/2, Ly/2, Ny);
      x = linspace(-Lx/2, Lx/2, Nx);

      % Define grid
      [X, Y] = meshgrid(x, y);
    
      % Extracts target cooridnates

      % Initialize indicator matric
      zone = false(size(X));
    
      % Add site
      for i = 1:size(coordinates, 1)
        zone = zone | (abs(X - coordinates(i, 2)) <= toll & ...
                      (abs(Y - coordinates(i, 1)) <= toll));
      end
    
      % Conversion to double
      zone = double(zone);
    end

    function plume_mask = create_plumes(app, Ly, Lx, dy, dx, V, toll, coordinates)
      %
      Vy = V(1);
      % Parameters ellipse
      r = 0.15;                       % Horizontal semi-axis
      c = sqrt(r^2*(1 + Vy)^2 - r^2); % Vertical translation
      denx = r^2;                     % Denominator of x^2
      deny = r^2*(1 + Vy)^2;          % Denominator of y^2
      
      % Domain and grid calculation
      x = -Lx/2:dx:Lx/2;
      y = -Ly/2:dy:Ly/2;
      [X, Y] = meshgrid(x, y);
      
      % 3D matrix preallocation (Ny x Nx x Ncoordinates)
      Ncoordinates = size(coordinates, 1);
      plume_mask = false(length(y), length(x), Ncoordinates);
    
      % Calculate mask for each site
      for i = 1:Ncoordinates
        Ax = coordinates(i, 2);
        Ay = coordinates(i, 1);
        
        % Ellipse equation
        ellipse_eq = ((X - Ax).^2)/denx + ((Y - Ay + c).^2)/deny;
        % Binary mask
        plume_mask(:, :, i) = (ellipse_eq < 1 + toll);
      end
    
      % Convert to double for mathematical operations
      plume_mask = double(plume_mask);
    end

    function NablaC_vect = compute_NC_in_plumes(app, params, plume_mask, site_list)
      % Create 2D grid
      x = linspace(-params.Lx/2, params.Lx/2, params.Nx);
      y = linspace(-params.Ly/2, params.Ly/2, params.Ny);
      [X, Y] = meshgrid(x, y);

      % Initialize total gradient components
      grad_x_tot = zeros(size(X));
      grad_y_tot = zeros(size(X));

      % Loop over all coordinates
      for i = 1:size(site_list, 1)
        Ax = site_list(i, 2);
        Ay = site_list(i, 1);

        local_mask = plume_mask(:, :, i);
        gradC = params.NablaC(X, Y, Ax, Ay);

        grad_x = gradC(:, :, 2);
        grad_y = gradC(:, :, 1);

        grad_x_tot = grad_x_tot + grad_x .* local_mask;
        grad_y_tot = grad_y_tot + grad_y .* local_mask;
      end

      % Gradient in plumes
      NablaC_vect = cat(3, grad_y_tot, grad_x_tot);
    end
    
    % ------ PLOTS ------
    function plot_site_markers(app, ax, egg, food, smr)
      if size(egg, 1)
        % Breading coordinates markers : blue diamonds
        plot(ax, egg(:, 2), egg(:, 1), "d", ...
          "MarkerSize", 5, ...
          "LineWidth", 2, ...
          "MarkerEdgeColor", app.egg_color, ...
          "MarkerFaceColor", app.egg_color);
      end
    
      if size(food, 1)
        % Feeding coordinates markers : red triangles
        plot(ax, food(:, 2), food(:, 1), "^", ...
          "MarkerSize", 5, ...
          "LineWidth", 2, ...
          "MarkerEdgeColor", app.food_color, ...
          "MarkerFaceColor", app.food_color);
      end

      if size(smr, 1)
        % Sterile male release zones : 
        plot(ax, smr(:, 2), smr(:, 1), app.smr_MarkerType, ...
          "MarkerSize", app.smr_MarkerSize, ...
          "LineWidth", 2, ...
          "MarkerEdgeColor", app.smr_color, ...
          "MarkerFaceColor", app.smr_color);
      end

      if size(egg, 1) && size(food, 1)
      % Legend
      legend(ax, ["Breeding sites", "Feeding sites"], ...
        "Location", "northoutside", ...
        "Orientation", "horizontal", ...
        "FontSize", 12, ...
        "Box", "off", ...
        "Color", "none");
      end
    end

    function plot_mature_female(app, t, mature_female, y, x, dt, egg_sites, food_sites, smr_sites)
      % Plotting on the app's UIAxis
      axes(app.UIAxes);
      hold(app.UIAxes, "on")
      colormap(app.UIAxes, summer)
      imagesc(app.UIAxes, x, y, mature_female);

      % Dynamic color limits
      if ~isempty(mature_female)
        clim(app.UIAxes, [min(mature_female(:)), max(mature_female(:))]);
      end

      % Formatting axis
      axis(app.UIAxes, "equal");
      axis(app.UIAxes, "tight");
      set(app.UIAxes, "YDir", "normal");
      colorbar(app.UIAxes);
      title(app.UIAxes, sprintf("Mature Females at day %.1f", t*dt));
      xlabel(app.UIAxes, "x");
      ylabel(app.UIAxes, "y");

      % Plot Breeding and Feeding Sites
%       app.plot_combined_zones(params.y_vec, params.x_vec, egg_sites, food_sites, plume_mask)
      app.plot_site_markers(app.UIAxes, egg_sites, food_sites, smr_sites)

      drawnow
    end

    function plot_mature_females_over_time(app, t, dt, time, uf, ur, ub)
      axes(app.UIAxes_2);
      hold(app.UIAxes_2, "on")
      plot(app.UIAxes_2, time(1:t*dt+1), uf(1:t*dt+1), "r-", "LineWidth", 2)
      plot(app.UIAxes_2, time(1:t*dt+1), ur(1:t*dt+1), "g-", "LineWidth", 2)
      plot(app.UIAxes_2, time(1:t*dt+1), ub(1:t*dt+1), "b-", "LineWidth", 2)
      plot(app.UIAxes_2, time(1:t*dt+1), uf(1:t*dt+1) + ur(1:t*dt+1) + ub(1:t*dt+1), "k--", "LineWidth", 2)
      legend(app.UIAxes_2, ["Fertile females u_f", ...
        "Resting females u_r", ...
        "Egg-bearing females u_b", ...
        "Total females"], ...
       "Location", "northwest");
      hold(app.UIAxes_2, "off")
    end

    % ------ SYSTEM ------
    % Diffusion part
    function Deltau = diffusion(app, u_in, dy, dx, dt, D)
      % Inizializing of the second derivatives
      [Ny, Nx, Nc] = size(u_in);
      uyy = zeros(size(u_in));
      uxx = uyy;
      
      % Second derivative in y
      uyy(2:end-1, :, 2:end) = u_in(3:end, :, 2:end) - 2*u_in(2:end-1, :, 2:end) + u_in(1:end-2, :, 2:end);
%       for i = 2:Ny-1
%         for j = 1:Nx
%           for k = 2:Nc
%             uyy(i, j, k) = u_in(i+1, j, k) - 2*u_in(i, j, k) + u_in(i-1, j, k);
%           end
%         end
%       end
      % Total flux Cauchy BCs
      uyy(1, :, 2:end) = u_in(2, :, 2:end) - 2*u_in(1, :, 2:end) + (2*app.params.dy*app.data.V(1)/app.data.D)*u_in(1, :, 2:end) + u_in(2, :, 2:end);
      % Homogeneous Neumann BCs
      uyy(end, :, 2:end) = 2*(u_in(Ny-1, :, 2:end) - u_in(Ny, :, 2:end)); 
%       for j = 1:Nx
%         for k = 2:Nc
%           % Total flux Cauchy BCs
%           uyy(1, j, k) = u_in(2, j, k) - 2*u_in(1, j, k) + (2*app.params.dy*app.data.V(1)/app.data.D)*u_in(1, j, k) + u_in(2, j, k);
%           % Homogeneous Neumann BCs
%           uyy(end, j, k) = 2*(u_in(Ny-1, j, k) - u_in(Ny, j, k));
%         end
%       end
      uyy = uyy/(dy^2);
    
      % Second derivative in x
      uxx(:, 2:end-1, 2:end) = u_in(:, 3:end, 2:end) - 2*u_in(:, 2:end-1, 2:end) + u_in(:, 1:end-2, 2:end);
%       for i = 1:Ny
%         for j = 2:Nx-1
%           for k = 2:7
%             uxx(i, j, k) = u_in(i, j+1, k) - 2*u_in(i, j, k) + u_in(i, j-1, k);
%           end
%         end
%       end
      % Homogeneous Neumann BCs
      uxx(:, 1, 2:end) = 2*(u_in(:, 2, 2:end) - u_in(:, 1, 2:end));
      uxx(:, end, 2:end) = 2*(u_in(:, end-1, 2:end) - u_in(:, end, 2:end));
%       for i = 1:Ny
%         for k = 2:7
%           uxx(i, 1, k) = 2*(u_in(i, 2, k) - u_in(i, 1, k));
%           uxx(i, end, k) = 2*(u_in(i, Nx-1, k) - u_in(i, Nx, k));
%         end
%       end
      uxx = uxx/(dx^2);
    
      % D Delta(u)
      Deltau = u_in + dt * D * (uxx + uyy);
    end

    % Reaction part
    function u_out = reaction(app, u_in, params, data, sites)
      % Initialisation of the output
      u_out = zeros(size(u_in));

      % Indicator functions
      egg_mask = ones(params.Ny, params.Nx); % app.indicator.breeding;
      food_mask = ones(params.Ny, params.Nx); % app.indicator.feeding;

      % Stability parameter
      p = max([...
        data.etaA + data.MA, ...
        data.MY + data.betaY, ...
        data.Mf + data.mu_fr, ...
        data.Mf + data.mu_rb, ...
        data.Mf + data.mu_bf, ...
        data.Mm, ...
        data.Mms]);
  
      % Time step function
      phi = (exp(p*params.dt) - 1)/p;
      
      % Identity matrix
      I7 = eye(7);
      
      % Stable Metzler Matrix
      A0_base = zeros(7, 7);
      A0_base(1, 1) = data.etaA + data.MA;
      A0_base(2, 1) = -data.r*data.etaA;
      A0_base(2, 2) = data.betaY + data.MY;
      A0_base(3, 2) = -data.betaY;
      A0_base(3, 3) = data.Mf;
      A0_base(3, 5) = -data.mu_bf;
      A0_base(4, 3) = -data.mu_fr;
      A0_base(4, 4) = data.Mf+data.mu_rb;
      A0_base(5, 4) = -data.mu_rb;
      A0_base(5, 5) = data.Mf;
      A0_base(6, 1) = -(1-data.r)*data.etaA;
      A0_base(6, 6) = data.Mm;
      A0_base(7, 7) = data.Mms;
    
      % A1 matrix
      A1 = zeros(7, 7);
      A1(1, 5) = data.NEgg;

      % Precalculation of (I + phi * A1)
      I1 = I7 + phi * A1;

      % Loop
      for i = 1:params.Ny
        for j = 1:params.Nx
          u_r = squeeze(u_in(i, j, :));

          % beta_eff
          beta_eff = (u_r(6) + params.lambda_s * params.f_s * u_r(7)) / (u_r(6) + u_r(7) + eps);
          
          % A0 matrix construction
          A0 = A0_base;
          A0(1, 1) = A0(1, 1) + data.NEgg*u_r(5)*egg_mask(i, j)/data.K;
          A0(3, 2) = A0(3, 2) * beta_eff;
          A0(3, 3) = A0(3, 3) + data.mu_fr*food_mask(i, j);
          A0(3, 5) = A0(3, 5) * egg_mask(i, j);
          A0(4, 3) = A0(4, 3) * food_mask(i, j);
          A0(5, 5) = A0(5, 5) + data.mu_bf * egg_mask(i, j);

          I0 = I7 + phi * A0;

          % Updating
          u_out(i, j, :) = I0 \ I1 * u_r;
        end
      end
    end

    % ------BASIC REPRODUCTION NUMBER ------
    function compute_R0(app)
      app.R0 = app.data.NEgg * app.data.mu_rb * app.data.mu_fr * app.data.betaY * app.data.r * app.data.etaA ...
        / ((app.data.etaA + app.data.MA) * (app.data.MY + app.data.betaY) * ((app.data.Mf + app.data.mu_fr) * (app.data.Mf + app.data.mu_rb) * (app.data.Mf + app.data.mu_bf) - app.data.mu_fr * app.data.mu_rb * app.data.mu_bf));
    end

    % ------ EDIT FIELD UPDATING ------
    function updateTimeCompartmentField(app, t, dt)
      app.TimeEditField.Value = t * dt;
    end

    function updateMosquitoesCompartmentField(app, u_in)
          app.FeedingFemalesEditField.Value = sum(u_in(:, :, 3), "all");
          app.RestingFemalesEditField.Value = sum(u_in(:, :, 4), "all");
          app.BreedingFemalesEditField.Value = sum(u_in(:, :, 5), "all");
          app.TotalWildMalesEditField.Value = sum(u_in(:, :, 6), "all");
          app.TotalSterileMalesEditField.Value = sum(u_in(:, :, 7), "all");
          app.TotalFemalesEditField.Value = sum(u_in(:, :, 3), "all") + sum(u_in(:, :, 4), "all") + sum(u_in(:, :, 5), "all");
    end
    
    % ------ Sterile Males releases ------
    function sterile_males_release_zones(app)
      % Prepare 2D grid
      [X, Y] = meshgrid(app.params.x_vec, app.params.y_vec);
      
      % Create GUI elements
      [smr_fig, smr_ax, remaningEdit] = app.create_release_ui();

      % Plot existing site markers
      app.plot_site_markers(smr_ax, app.coordinates.breeding, app.coordinates.feeding, app.coordinates.sterile_males_releasing);

      % Plot grid nodes with clickable behavior
      app.plot_grid_nodes(smr_fig, smr_ax, X, Y, remaningEdit)

      % Pause execution until user conferms
      uiwait(smr_fig);
    end

    function [fig, ax, remaningEdit] = create_release_ui(app)
      % Create figure skeleton
      fig = uifigure("Name", "Sterile males release siytes", ...
        "Position", [100 100 700 450], ...
        "CloseRequestFcn", @(src, event) uiresume(src)); % To resume the code when closing the window
      fig.Icon = fullfile(pwd, 'mosquito_icon.png');
      
      % Label of numeric field for remaining mosquitoes
      uilabel(fig, ...
        "Text", "Remaining number of sterile males:", ...
        "Position", [420 60 250 22], ...
        "FontWeight", "bold", ...
        "FontSize", 11);

      % Numeric field for remaining mosquitoes
      remaningEdit = uieditfield(fig, "numeric", ...
        "Position", [420 30 190 30], ...
        "Editable", "off", ...
        "Value", app.params.Lambda_s - sum(app.indicator.sterile_males_releasing, "all"), ...
        "Limits", [0 app.params.Lambda_s], ...
        "FontWeight", "bold", ...
        "Tooltip", "Remaining sterile males to release");

      % Confirmation button
      uibutton(fig, "Position", [300 20 100 40], ...
        "Text", "Confirm", ...
        "ButtonPushedFcn", @(btn, event) finalize_SIT(app, fig));

      % Axes inside the smr_fig
      ax = uiaxes(fig, "Position", [50 80 600 330]);
      title(ax, "Define sterile males release sites");
      xlabel(ax, "x")
      ylabel(ax, "y")
      hold(ax, "on")
    end

    function plot_grid_nodes(app, fig, ax, X, Y, remaningEdit)
      for index = 1:numel(X)
        x_node = X(index);
        y_node = Y(index);
        pl = plot(ax, x_node, y_node, "o", ...
          "MarkerSize", 2, ...
          "MarkerEdgeColor", "b", ...
          "MarkerFaceColor", "b", ...
          "ButtonDownFcn", @(src, event) on_node_click(app, fig, ax, X, Y, index, remaningEdit));
        pl.Annotation.LegendInformation.IconDisplayStyle = "off"; % TODO : La posso mettere fuori dal for?
      end
    end

    function on_node_click(app, fig, ax, X, Y, idx, remaningEdit)
      [i, j] = ind2sub(size(X), idx);

      % Input dialog
      prompt = sprintf("Number of mosquitoes in (%d, %d):", j, i);
      user_input = inputdlg(prompt, "Insert a number: ", [1 35], "0");

      % Control if input is empty
      if isempty(user_input)
        return;
      end
      
      % If user_input is not empty
      value = str2double(user_input{1});
      max_remaining = app.params.Lambda_s;

      % Validate input
      error_message = app.validate_input(value, app.indicator.sterile_males_releasing, i, j, max_remaining);
      if ~isempty(error_message)
        uialert(fig, error_message, "Invalid Input", "Icon", "warning")
        return;
      end

      % Update app data
      update_sterile_male_count(app, i, j, value);

      % Update sterile male releses coordinates
      app.coordinates.sterile_males_releasing = [app.coordinates.sterile_males_releasing; Y(i, 1),X(1, j)];
      % Update UI
      total_released = sum(app.indicator.sterile_males_releasing, "all");
      remaningEdit.Value = max_remaining - total_released;
      app.print_new_smr_coordinates(ax);
      app.print_value_on_grid(ax, X, Y, i, j, value);
    end

    function msg = validate_input(app, value, current_matrix, i, j, max_val)
      msg = string.empty;

      if isnan(value)
        msg = "The input must be a number.";
        return
      end

      if value <= 0 || mod(value, 1)
        msg = "The number of sterile males must be a positive integer.";
        return
      end
  
      temp = current_matrix;
      temp(i, j) = value;
      if sum(temp, "all") > max_val
        msg = "The total number of sterile males exceeds the allowed maximum.";
      end
    end

    function update_sterile_male_count(app, i, j, value)
      app.indicator.sterile_males_releasing(i, j) = value;
    end

    function print_value_on_grid(app, ax, X, Y, i, j, value)
      text(ax, X(i, j), Y(i, j), num2str(value), ...
        "HorizontalAlignment", "center", ...
        "VerticalAlignment", "middle", ...
          "FontSize", 10, ...
          "Color", "m", ...
          "FontWeight", "bold");
    end
    
    function print_new_smr_coordinates(app, ax)
      plot(ax, app.coordinates.sterile_males_releasing(:, 2), app.coordinates.sterile_males_releasing(:, 1), app.smr_MarkerType, ...
          "MarkerSize", app.smr_MarkerSize, ...
          "LineWidth", 2, ...
          "MarkerEdgeColor", app.smr_color, ...
          "MarkerFaceColor", app.smr_color);
    end
    
    function finalize_SIT(app, fig)
      uiresume(fig);
      delete(fig);
    end


    % ARRIVATO QUI AD OTTIMIZZARE
    % UPDATE THE PLOT
    function update_destriy_coordinates_plot(app, src, event, siteSwitch, fig, ax)
      cla(ax);
      hold(ax, "on");

      % Title of the plot
      title(ax, "Destroy " + siteSwitch.Value + " sites");

      % Dermine thy kind of site to destroy
      if siteSwitch.Value == "Breeding"
        coordinates_to_destroy = app.coordinates.breeding;
        markers_type = "d";
        markers_color = app.egg_color;
      else
        coordinates_to_destroy = app.coordinates.feeding;
        markers_type = "^";
        markers_color = app.food_color;
      end

      % Plot nodes
      for i = 1:size(coordinates_to_destroy, 1)
        x = coordinates_to_destroy(i, 2); % Colonna 2 = x;
        y = coordinates_to_destroy(i, 1); % Colonna 1 = y;
        p = plot(ax, x, y, markers_type, ...
          "MarkerSize", 6, ...
          "MarkerEdgeColor", markers_color, ...
          "MarkerFaceColor", markers_color, ...
          "ButtonDownFcn", @(src, event) destroy_site_clicked(app, src, event, siteSwitch, fig, ax, coordinates_to_destroy));
        p.Annotation.LegendInformation.IconDisplayStyle = "off";
      end
    end

    % Function that gets the current point
    function destroy_site_clicked(app, src, event, siteSwitch, fig, ax, coordinates_to_destroy)
      % Get click position
      cp = ax.CurrentPoint;
      x_click = cp(1, 1);
      y_click = cp(1, 2);

      % I evaluate the euclidean distance between the click and each site
      distances = sqrt((coordinates_to_destroy(:, 2) - x_click).^2 + (coordinates_to_destroy(:, 1) - y_click).^2);
      % I find the index of the nearest site
      [~, idx] = min(distances);

      % I extract the cooridnates of the nearest site
      site_x = coordinates_to_destroy(idx, 2);
      site_y = coordinates_to_destroy(idx, 1);

      destroy_clicked_site(app, src, event, siteSwitch, fig, ax, site_x, site_y, coordinates_to_destroy)
    end

    function destroy_clicked_site(app, src, event, siteSwitch, fig, ax, site_x, site_y, coordinates_to_destroy)
      % Tollerance
      toll = 1e-6;

      % Search in egg_sites
      diff_egg = (abs(app.coordinates.breeding(:, 2) - site_x) < toll) & (abs(app.coordinates.breeding(:, 1) - site_y) < toll);
      if any(diff_egg)
        app.coordinates.breeding(diff_egg, :) = []; % Delete the line
        update_destriy_coordinates_plot(app, src, event, siteSwitch, fig, ax)
        return
      end
    
      % Search in food_sites
      diff_food = (abs(app.coordinates.feeding(:, 2) - site_x) < toll) & (abs(app.coordinates.feeding(:, 1) - site_y) < toll);
      if any(diff_food)
        app.coordinates.feeding(diff_food, :) = []; % Delete the line
        update_destriy_coordinates_plot(app, src, event, siteSwitch, fig, ax)
        return
      end
    end

    function define_new_coordinates(app, src, event, siteSwitch, fig, ax, X, Y)
      % Get click position
      cp = ax.CurrentPoint;
      x_click = cp(1, 1);
      y_click = cp(1, 2);

      % Find the nearest node at the point clicked
      [~, j] = min(abs(X(1, :) - x_click));
      [~, i] = min(abs(Y(:, 1) - y_click));
      
      % Dermine thy kind of site to destroy
      if siteSwitch.Value == "Breeding"
        app.coordinates.breeding = [app.coordinates.breeding; Y(i, 1),X(1, j)];
      else
        app.coordinates.feeding = [app.coordinates.feeding; Y(i, 1),X(1, j)];
      end

      update_new_coordinates_plot(app, [], [], siteSwitch, fig, ax, X, Y)
    end

    function update_new_coordinates_plot(app, src, event, siteSwitch, fig, ax, X, Y)
      cla(ax);
      hold(ax, "on");

      % Title of the figure
      title(ax, "Define new " + siteSwitch.Value + " sites");

      % Dermine thy kind of site to add
      if siteSwitch.Value == "Breeding"
        app.plot_site_markers(ax, app.coordinates.breeding, [], [])
      else
        app.plot_site_markers(ax, [], app.coordinates.feeding, [])
      end

      
      % Plot nodes
      for i = 1:numel(X)
        x = X(i); % Colonna 2 = x;
        y = Y(i); % Colonna 1 = y;
        p = plot(ax, x, y, "o", ...
          "MarkerSize", 2, ...
          "MarkerEdgeColor", "b", ...
          "MarkerFaceColor", "b", ...
          "ButtonDownFcn", @(src, event) define_new_coordinates(app, src, event, siteSwitch, fig, ax, X, Y));
        p.Annotation.LegendInformation.IconDisplayStyle = "off";
      end
    end

    % Update the date
    function update_date(app, t)
      date = datetime("today") + t;
      app.DateEditField.Value = num2str(day(date)) + "/" + num2str(month(date)) + "/" + num2str(year(date));
    end

    function number_of_the_day = current_day(app, date)
      number_of_the_day = day(date, "dayofyear");
    end

    function temperature = define_temperature(app, date)
      % Extract the current day
      number_of_the_day = app.current_day(date);

      % Temperature settings
      mean = 15;
      height = 10;
      phase = -80;% The maximum is in July
      
      % Stochastic component
      rng(42); % seed
      noise = 2 * randn(1); % Standard deviation of 2°C
      
      % Temperature vector
      temperature = mean + height * sin(2 * pi * (number_of_the_day + phase) / app.params.T) + noise;
    end
  
    function plot_temperature(app, temperature_vector)
      % Create figure skeleton
      fig = uifigure("Name", "Temperature", ...
        "Position", [100 100 700 450], ...
        "CloseRequestFcn", @(src, event) uiresume(src)); % To resume the code when closing the window
      fig.Icon = fullfile(pwd, 'mosquito_icon.png');

      % Confirmation button
      uibutton(fig, "Position", [300 20 100 40], ...
        "Text", "Confirm", ...
        "ButtonPushedFcn", @(btn, event) finalize_SIT(app, fig));

      % Axes inside the smr_fig
      ax = uiaxes(fig, "Position", [50 80 600 330]);
      title(ax, "Temperature");
      xlabel(ax, "x")
      ylabel(ax, "y")
      hold(ax, "on")

      % Plot
      time = 1:app.params.T+1;
      plot(ax, time, temperature_vector, "b");
    end
    % end methods
  end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app)
      % Defines all structures
      app.params = app.init_parameters();
      
      % Define temperature vector
      temperature = zeros(1, app.params.T + 1);
      temperature(1) = define_temperature(app, datetime("today"));
%       temperature = 25;%define_temperature(app, datetime("today"));
      app.data  = app.init_empirical_data(temperature(1));
%       app.data  = app.init_empirical_data(temperature);
      app.coordinates  = app.get_coordinates_coordinates();
      app.indicator = app.build_indicator_matrix();
      app.plumes = app.compute_plumes();
      
      % Sterile Insect Technique Edit Field
      app.ModifyreleasesperiodicityEditField.Value = app.params.tau;
      app.ModifynumberofmalesperreleaseEditField.Value = app.params.Lambda_s;
      
      % Compute \Nabla C_i in plumes
      NablaC_b = app.compute_NC_in_plumes(app.params, app.plumes.ind_pb, app.coordinates.breeding);
      NablaC_f = app.compute_NC_in_plumes(app.params, app.plumes.ind_pf, app.coordinates.feeding);
      NablaC_bf = NablaC_b + NablaC_f;

      % Breeding and feeding coordinates and their plumes

      % Basic Reproduction Number
      app.compute_R0();
      app.BasicReproductionNumberEditField.Value = app.R0;
      
      % Initial condition
      u = zeros(app.params.Ny, app.params.Nx, 7);
      u(:, :, 1) = 10*app.indicator.breeding;
      
      % Set variables for evolution in time's plot
      time = 0:app.params.Nt+1;
      u_f_t = zeros(1, app.params.Nt+1);
      u_r_t = u_f_t;
      u_b_t = u_f_t;

%       % CSV file opening
%       fid = fopen('daily_data.csv', 'w');
%       fprintf(fid, 'Day;Temperature;NEgg;K;r;etaA;betaY;mu_fr;mu_rb;mu_bf;MA;MY;Mf;Mm;Mms;V;D\n');

      % Evolution of the system over time
      for t = 1:app.params.Nt
        u_adv_div = zeros(app.params.Ny, app.params.Nx, 7);
        u_adv_wind = zeros(app.params.Ny, app.params.Nx, 7);
        u_diff = app.diffusion(u, app.params.dy, app.params.dx, app.params.dt, app.data.D);
%         u_diff = u;
        u = app.reaction(u_diff, app.params, app.data, app.coordinates);
        
        if (app.sterile_males_releases_bool) && (mod(t - app.params.Tstart, app.params.tau / app.params.dt) < app.params.dt)
          u(:, :, 7) = u(:, :, 7) + app.params.rho_s * app.params.Lambda_s * app.indicator.sterile_males_releasing;
        end % TODO : Modifica questo in modo che i rilasci inizino nel giorno successivo in cui si attivi l'opzione

        % Show result
        if (~mod(t, 1/app.params.dt))
        % Update temperature
          temperature(t*app.params.dt+1) = app.define_temperature(datetime("today") + t*app.params.dt);
          app.data  = app.init_empirical_data(temperature(t*app.params.dt+1));
%           temperature = app.define_temperature(datetime("today") + t*app.params.dt);
%           app.data  = app.init_empirical_data(temperature);

%           % CSV file writing
%           fprintf(fid, '%.0f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f;%.4f\n', ...
%             round(t * app.params.dt), temperature, app.data.NEgg, app.data.K, app.data.r, app.data.etaA, app.data.betaY, app.data.mu_fr, app.data.mu_rb, app.data.mu_bf, ...
%             app.data.MA, app.data.MY, app.data.Mf, app.data.Mm, app.data.Mms, app.data.V(1), app.data.D);

          % Update numeric field
          app.updateTimeCompartmentField(t, app.params.dt)
          app.update_date(t * app.params.dt)
          app.updateMosquitoesCompartmentField(u)
          app.TemperatureEditField.Value = temperature(t*app.params.dt+1);
%           app.TemperatureEditField.Value = temperature;

          % Plot mature females
          app.plot_mature_female(t, u(:, :, 3) + u(:, :, 4) + u(:, :, 5), ...
            app.params.y_vec, app.params.x_vec, app.params.dt, ...
            app.coordinates.breeding, app.coordinates.feeding, ...
            app.coordinates.sterile_males_releasing);

          % Update variables for time-plot
          u_f_t(t*app.params.dt+1) = sum(u(:, :, 3), "all");
          u_r_t(t*app.params.dt+1) = sum(u(:, :, 4), "all");
          u_b_t(t*app.params.dt+1) = sum(u(:, :, 5), "all");
          
          % Plot mature female over time
          app.plot_mature_females_over_time(t, app.params.dt, time, u_f_t, u_r_t, u_b_t)
        end
      end

      % Plot temperature
      % TODO : Da inserire il grafico delle temperature
%       % CSV file closure
%       fclose(fid);
    end

    % Button pushed function: ModifynumberofmalesperreleaseButton
    function modify_number_sterile_males_per_release(app, event)
      temp_smr_number = inputdlg(sprintf("Insert a positive number:"), ...
        "Number of sterile males per release.", [1 65], {num2str(app.params.Lambda_s)});
      
      if ~isempty(temp_smr_number)
        value = str2double(temp_smr_number{1});
        
        % Checks that the user has entered a valid number
        if isnan(value)
          errordlgHandle = warndlg("The input must be a number", ...
            "Invalid input");
          uiwait(app, errordlgHandle);
            return
        end

        % Checks that value is a positive number
        if (value < 0) || (mod(value,1))
          errordlgHandle = warndlg("The number of sterile males released into this area must be a positive integer.", ...
            "Invlid input");
          uiwait(app, errordlgHandle);
          return;
        end

        app.params.Lambda_s = value;
        app.ModifynumberofmalesperreleaseEditField.Value = app.params.Lambda_s;
      end
    end

    % Button pushed function: ModifyreleasesperiodicityButton
    function modift_relaese_periodicity(app, event)
      temp_periodicity = inputdlg(sprintf("Insert a positive number:"), ...
        "Periodicity of the releases.", [1 65], {num2str(app.params.tau)});
      if ~isempty(temp_periodicity)

        value = str2double(temp_periodicity{1});
        
        % Checks that the user has entered a valid number
        if isnan(value)
          errordlgHandle = warndlg("The input must be a number", ...
            "Invalid input");
          uiwait(app, errordlgHandle);
            return
        end

        % Checks that value is a positive integer
        if value <= 0 || (mod(value,1))
          errordlgHandle = warndlg("The periodicity of sterile males released into this area must be positive.", ...
            "Invlid input");
          uiwait(app, errordlgHandle);
          return;
        end
        
        app.params.tau = value;
        app.ModifyreleasesperiodicityEditField.Value = value;
      end
    end

    % Value changed function: SterileMalesReleasesSwitch
    function boolean_sterile_males_releases(app, event)
      app.sterile_males_releases_bool = strcmp(app.SterileMalesReleasesSwitch.Value, "On");
    end

    % Button pushed function: ModifySITsitesButton
    function modify_SIT_sites(app, event)
      % Delete all the sterile insect relese coordinates
      app.coordinates.sterile_males_releasing = [];
      
      % Delete all the sterile males to release
      app.indicator.sterile_males_releasing = zeros(app.params.Ny, app.params.Nx);
      
      % New sterile insect relese coordinates
      app.sterile_males_release_zones();
    end

    % Button pushed function: DestroysitesButton
    function destroy_sites(app, event)
      % 2D grid
      %[X, Y] = meshgrid(app.params.x_vec, app.params.y_vec);

      % New figure (only the skeleton, without nothing)
      destroy_fig = uifigure("Name", "Destroy a site", ...
        "Position", [100 100 700 450], ...
        "CloseRequestFcn", @(src, event) uiresume(src)); % To resume the code when closing the window
      destroy_fig.Icon = fullfile(pwd, 'mosquito_icon.png');
      fig_width = destroy_fig.Position(3);
      fig_height = destroy_fig.Position(4);

      % Switch position
      switch_widht = 50;
      switch_height = 30;
      x_pos = 20; % margine sinistro
      y_pos = (fig_height - switch_height)/2;

      % Axes inside the destroy_fig
      destroy_ax = uiaxes(destroy_fig, "Position", [50 80 600 330]);
      xlabel(destroy_ax, "x")
      ylabel(destroy_ax, "y")
      axis(destroy_ax, [-app.params.Lx/2 app.params.Lx/2 -app.params.Ly/2 app.params.Ly/2]);
      hold(destroy_ax, "on")

      % Switch
      siteSwitch = uiswitch(destroy_fig, "toggle", ...
        "Items", ["Breeding", "Feeding"], ...
        "Position", [x_pos y_pos switch_widht switch_height], ...
        "Value", "Breeding"); % Valore iniziale
      siteSwitch.ValueChangedFcn = @(src, event) update_destriy_coordinates_plot(app, src, event, siteSwitch, destroy_fig, destroy_ax);
      % Inizializzazione del plot
      update_destriy_coordinates_plot(app, [], [], siteSwitch, destroy_fig, destroy_ax);

      % Title of the plot
      title(destroy_ax, "Destroy " + siteSwitch.Value + " sites");

      % Confirmation button
      button_widht = 100;
      button_height = 40;
      x_pos = (fig_width - button_widht)/2;
      y_pos = 20;
      uibutton(destroy_fig, "Position", [x_pos y_pos button_widht button_height], ...
        "Text", "Confirm", ...
        "ButtonPushedFcn", @(btn, event) finalize_SIT(app, destroy_fig));

      % Stop the execution
      uiwait(destroy_fig)
    end

    % Button pushed function: NewsitesButton
    function new_sites(app, event)
      % 2D grid
      [X, Y] = meshgrid(app.params.x_vec, app.params.y_vec);
      
      % New figure (only the skeleton, with nothing)
      new_sites_fig = uifigure("Name", "Define new sites", ...
        "Position", [100 100 700 450], ...
        "CloseRequestFcn", @(src, event) uiresume(src)); % To resume the code when closing the window
      new_sites_fig.Icon = fullfile(pwd, 'mosquito_icon.png');
      
      % Switch position
      fig_height = new_sites_fig.Position(4);
      switch_widht = 50;
      switch_height = 30;
      x_pos = 20; % margine sinistro
      y_pos = (fig_height - switch_height)/2;

      % Confirmation button
      uibutton(new_sites_fig, "Position", [300 20 100 40], ...
        "Text", "Confirm", ...
        "ButtonPushedFcn", @(btn, event) finalize_SIT(app, new_sites_fig));

      % Axes inside the new_sites_fig
      new_sites_ax = uiaxes(new_sites_fig, "Position", [50 80 600 330]);
      xlabel(new_sites_ax, "x")
      ylabel(new_sites_ax, "y")
      hold(new_sites_ax, "on")
      
      % Switch
      siteSwitch = uiswitch(new_sites_fig, "toggle", ...
        "Items", ["Breeding", "Feeding"], ...
        "Position", [x_pos y_pos switch_widht switch_height], ...
        "Value", "Breeding"); % Valore iniziale
      siteSwitch.ValueChangedFcn = @(src, event) update_new_coordinates_plot(app, src, event, siteSwitch, new_sites_fig, new_sites_ax, X, Y);
      % Inizializzazione del plot
      update_new_coordinates_plot(app, [], [], siteSwitch, new_sites_fig, new_sites_ax, X, Y);

      % Dermine thy kind of site to destroy
      if siteSwitch.Value == "Breeding"
        app.plot_site_markers(new_sites_ax, app.coordinates.breeding, [], [])
      else
        app.plot_site_markers(new_sites_ax, [], app.coordinates.feeding, [])
      end

      % Title of the figure
      title(new_sites_ax, "Define new " + siteSwitch.Value + " sites");
      

      % Plot nodes
      for i = 1:numel(X)
        x = X(i);
        y = Y(i);
        p = plot(new_sites_ax, x, y, 'o', ...
          "MarkerSize", 2, ...
          "MarkerEdgeColor", "b", ...
          "MarkerFaceColor", "b", ...
          "ButtonDownFcn", @(src, event) define_new_coordinates(app, src, event, siteSwitch, new_sites_fig, new_sites_ax, X, Y));
        p.Annotation.LegendInformation.IconDisplayStyle = "off";
      end
      

      % Stop the execution
      uiwait(new_sites_fig)
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
      currentFigureWidth = app.ControlstrategiesforawildmosquitoespopulationUIFigure.Position(3);
      if(currentFigureWidth <= app.onePanelWidth)
          % Change to a 3x1 grid
          app.GridLayout.RowHeight = {479, 479, 479};
          app.GridLayout.ColumnWidth = {'1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 1;
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 3;
          app.RightPanel.Layout.Column = 1;
      elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
          % Change to a 2x2 grid
          app.GridLayout.RowHeight = {479, 479};
          app.GridLayout.ColumnWidth = {'1x', '1x'};
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = [1,2];
          app.LeftPanel.Layout.Row = 2;
          app.LeftPanel.Layout.Column = 1;
          app.RightPanel.Layout.Row = 2;
          app.RightPanel.Layout.Column = 2;
      else
          % Change to a 1x3 grid
          app.GridLayout.RowHeight = {'1x'};
          app.GridLayout.ColumnWidth = {237, '1x', 455};
          app.LeftPanel.Layout.Row = 1;
          app.LeftPanel.Layout.Column = 1;
          app.CenterPanel.Layout.Row = 1;
          app.CenterPanel.Layout.Column = 2;
          app.RightPanel.Layout.Row = 1;
          app.RightPanel.Layout.Column = 3;
      end
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      % Get the file path for locating images
      pathToMLAPP = fileparts(mfilename('fullpath'));
      app.ControlstrategiesforawildmosquitoespopulationUIFigure = uifigure('Visible', 'off');
      app.ControlstrategiesforawildmosquitoespopulationUIFigure.Name = 'Control strategies for a wild mosquitoes population';
      app.ControlstrategiesforawildmosquitoespopulationUIFigure.Icon = fullfile(pathToMLAPP, 'mosquito_icon.png');
      app.ControlstrategiesforawildmosquitoespopulationUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.GridLayout = uigridlayout(app.ControlstrategiesforawildmosquitoespopulationUIFigure);
      app.LeftPanel = uipanel(app.GridLayout);
      app.LeftPanel2 = uipanel(app.LeftPanel);
      app.TimeEditFieldLabel = uilabel(app.LeftPanel2);
      app.TimeEditFieldLabel.Text = 'Time';
      app.TimeEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.TimeEditField.Limits = [0 Inf];
      app.TimeEditField.Editable = 'off';
      app.FeedingFemalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.FeedingFemalesEditFieldLabel.Text = 'Feeding Females';
      app.FeedingFemalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.FeedingFemalesEditField.Limits = [0 Inf];
      app.FeedingFemalesEditField.Editable = 'off';
      app.RestingFemalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.RestingFemalesEditFieldLabel.Text = 'Resting Females';
      app.RestingFemalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.RestingFemalesEditField.Limits = [0 Inf];
      app.RestingFemalesEditField.Editable = 'off';
      app.BreedingFemalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.BreedingFemalesEditFieldLabel.Text = 'Breeding Females';
      app.BreedingFemalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.BreedingFemalesEditField.Limits = [0 Inf];
      app.BreedingFemalesEditField.Editable = 'off';
      app.TotalFemalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.TotalFemalesEditFieldLabel.Text = 'Total Females';
      app.TotalFemalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.TotalFemalesEditField.Limits = [0 Inf];
      app.TotalFemalesEditField.Editable = 'off';
      app.DestroysitesButton = uibutton(app.LeftPanel2, 'push');
      app.DestroysitesButton.ButtonPushedFcn = createCallbackFcn(app, @destroy_sites, true);
      app.DestroysitesButton.Text = 'Destroy sites';
      app.NewsitesButton = uibutton(app.LeftPanel2, 'push');
      app.NewsitesButton.ButtonPushedFcn = createCallbackFcn(app, @new_sites, true);
      app.NewsitesButton.Text = 'New sites';
      app.SterileMalesReleasesSwitchLabel = uilabel(app.LeftPanel2);
      app.SterileMalesReleasesSwitchLabel.Text = {'Sterile'; 'Males'; 'Releases'};
      app.SterileMalesReleasesSwitch = uiswitch(app.LeftPanel2, 'toggle');
      app.SterileMalesReleasesSwitch.ValueChangedFcn = createCallbackFcn(app, @boolean_sterile_males_releases, true);
      app.TotalWindMalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.TotalWindMalesEditFieldLabel.Text = 'Total Wild Males';
      app.TotalWildMalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.TotalWildMalesEditField.Limits = [0 Inf];
      app.TotalWildMalesEditField.Editable = 'off';
      app.TotalSterileMalesEditFieldLabel = uilabel(app.LeftPanel2);
      app.TotalSterileMalesEditFieldLabel.Text = 'Total Sterile Males';
      app.TotalSterileMalesEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.TotalSterileMalesEditField.Limits = [0 Inf];
      app.TotalSterileMalesEditField.Editable = 'off';
      app.BasicReproductionNumberEditFieldLabel = uilabel(app.LeftPanel2);
      app.BasicReproductionNumberEditFieldLabel.Text = 'Basic Reproduction Number';
      app.BasicReproductionNumberEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.BasicReproductionNumberEditField.ValueDisplayFormat = '%.5f';
      app.BasicReproductionNumberEditField.Editable = 'off';
      app.ModifynumberofmalesperreleaseButton = uibutton(app.LeftPanel2, 'push');
      app.ModifynumberofmalesperreleaseButton.ButtonPushedFcn = createCallbackFcn(app, @modify_number_sterile_males_per_release, true);
      app.ModifynumberofmalesperreleaseButton.Text = 'Modify number of males per release';
      app.ModifynumberofmalesperreleaseEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.ModifynumberofmalesperreleaseEditField.Limits = [0 Inf];
      app.ModifynumberofmalesperreleaseEditField.Editable = 'off';
      app.ModifyreleasesperiodicityButton = uibutton(app.LeftPanel2, 'push');
      app.ModifyreleasesperiodicityButton.ButtonPushedFcn = createCallbackFcn(app, @modift_relaese_periodicity, true);
      app.ModifyreleasesperiodicityButton.Text = 'Modify releases'' periodicity';
      app.ModifyreleasesperiodicityEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.ModifyreleasesperiodicityEditField.Limits = [1 Inf];
      app.ModifyreleasesperiodicityEditField.Editable = 'off';
      app.ModifyreleasesperiodicityEditField.Value = 1;
      app.ModifySITsitesButton = uibutton(app.LeftPanel2, 'push');
      app.ModifySITsitesButton.ButtonPushedFcn = createCallbackFcn(app, @modify_SIT_sites, true);
      app.ModifySITsitesButton.Text = 'Modify SIT sites';
      app.DateEditField = uieditfield(app.LeftPanel2, 'text');
      app.DateEditField.Editable = 'off';
      app.DateEditFieldLabel = uilabel(app.LeftPanel2);
      app.DateEditFieldLabel.Text = 'Data';
      app.TemperatureEditField = uieditfield(app.LeftPanel2, 'numeric');
      app.TemperatureEditField.ValueDisplayFormat = '%.4f';
      app.TemperatureEditField.Editable = 'off';
      app.TemperatureEditFieldLabel_2 = uilabel(app.LeftPanel2);
      app.TemperatureEditFieldLabel_2.Text = 'Temp.';
      app.TemperatureEditFieldLabel_1 = uilabel(app.LeftPanel2);
      app.TemperatureEditFieldLabel_1.Text = '°C';
      app.CenterPanel = uipanel(app.GridLayout);
      app.RightPanel2 = uipanel(app.CenterPanel);
      app.UIAxes = uiaxes(app.RightPanel2);
      title(app.UIAxes, 'Spatial dispersion of the adult female mosquitoes')
      xlabel(app.UIAxes, 'X')
      ylabel(app.UIAxes, 'Y')
      zlabel(app.UIAxes, 'Z')
      app.RightPanel = uipanel(app.GridLayout);
      app.UIAxes_2 = uiaxes(app.RightPanel);
      title(app.UIAxes_2, 'Total evolution of adult female mosquitoes')
      xlabel(app.UIAxes_2, 'X')
      ylabel(app.UIAxes_2, 'Y')
      zlabel(app.UIAxes_2, 'Z')
      % Show the figure after all components are created
      app.ControlstrategiesforawildmosquitoespopulationUIFigure.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = dynamic_mosquitoes

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.ControlstrategiesforawildmosquitoespopulationUIFigure)

      % Execute the startup function
      runStartupFcn(app, @startupFcn)

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.ControlstrategiesforawildmosquitoespopulationUIFigure)
    end
  end
end